classdef helpDrawerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DRAWHELPINGAPPLabel       matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        ChargerImageButton        matlab.ui.control.Button
        LancerButton              matlab.ui.control.Button
        FixerSwitchLabel          matlab.ui.control.Label
        FixerSwitch               matlab.ui.control.Switch
        UIAxes2                   matlab.ui.control.UIAxes
        LongueurEditField_2Label  matlab.ui.control.Label
        LongueurEditField         matlab.ui.control.EditField
        LargeurEditFieldLabel     matlab.ui.control.Label
        LargeurEditField          matlab.ui.control.EditField
        TailleImageLabel          matlab.ui.control.Label
        UIAxes4                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MATCHING_POINTS_NUMBER = 7; % Number of matching points between the reference image and the image in the video frame
        referenceImage = imread("reference.jpeg"); % The reference Image
        image % The replacement Image
        imageContour % The contour of the replacement Image
        fix = "NON" % Whenever to fix the replacement Image or to continue tracing
        camera = webcam(); % Get instance of the camera        
    end
    
    methods (Access = private)
        
        function image = getImageContourFromApp(~, image)
            %getImageContour Summary of this function goes here
            %   Detailed explanation goes here
            imageName = "myfilename.png";
            
            image_gray = rgb2gray(image);
            BW = imbinarize(image_gray);
        
            [B,~] = bwboundaries(BW,'noholes');
            fig = figure;
            
            for k = 1:length(B)
               boundary = B{k};
               plot(boundary(:,1), boundary(:,2), 'r', 'LineWidth', 4)
               hold on
            end
            hold off
            set(gca,'visible','off')
            imwrite(getframe(gca).cdata, imageName);
            set(gca,'visible','off')
            close(fig);
            
            image = imread(imageName);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChargerImageButton
        function ChargerImageButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
            ImageFile = imread(fullname);
            app.image = ImageFile;            
            contour = getImageContourFromApp(app, ImageFile);
            bw = sum(contour,3) > 700;        % a binary image to overlay
            mask = cast(bw, class(contour));  % ensure the types are compatible
            app.imageContour = contour .* repmat(mask, [1 1 3]);  % apply the mask
            imagesc(app.UIAxes4, ImageFile);
            imagesc(app.UIAxes2, app.referenceImage);
            refImageSize = size(app.referenceImage);
            app.LongueurEditField.Value = string(refImageSize(1,1));
            app.LargeurEditField.Value = string(refImageSize(1,2));

        end

        % Button pushed function: LancerButton
        function LancerButtonPushed(app, event)
            referenceImageGray = rgb2gray(app.referenceImage);
            referencePts = detectSURFFeatures(referenceImageGray);
            referenceFeatures = extractFeatures(referenceImageGray, referencePts);
            
            % Capture one frame to get its size.
            cameraFrame = snapshot(app.camera);
            frameSize = size(cameraFrame);
            
            % Create the video player object.
            videoPlayer = vision.VideoPlayer('Position', [100 100 [frameSize(2), frameSize(1)]+30]);

            runLoop = true;
            frameCount = 0;
            
            while runLoop && frameCount < 400
               %% Detect SURF features in webcam frame
                frameCount = frameCount + 1;
                cameraFrame = snapshot(app.camera);
                cameraFrameGray = rgb2gray(cameraFrame);
                cameraPts = detectSURFFeatures(cameraFrameGray);
                
                %% Try to match the reference Image and the camera frame features
            
                cameraFeatures = extractFeatures(cameraFrameGray, cameraPts);
                idxPairs = matchFeatures(cameraFeatures, referenceFeatures);
                idxPairsSize = size(idxPairs(:, 2), 1);
                try
                    if idxPairsSize > app.MATCHING_POINTS_NUMBER
                        if app.fix == "NON"
                            
                            % Store the SURF points that were matched
                            matchedCameraPts = cameraPts(idxPairs(:,1));
                            matchedReferencePts = referencePts(idxPairs(:,2));
                            
                            %% Get geometric tansformation between reference Image and webcam Frame
            
                            [referenceTransform, inlierReferencePts, inlierCameraPts] = estimateGeometricTransform(matchedReferencePts, matchedCameraPts, 'Similarity');
            
                            %% Rescale Replacement Video frame
            
                            % Get replacement and reference dimensions
                            repDims = size(app.imageContour(:, :, 1));
                            sizer = [str2double(app.LongueurEditField.Value), str2double(app.LargeurEditField.Value), 3];
                            refDims = sizer;% size(app.referenceImage);
            
                            % Find transformation that scales video frame to replacement image size
                            % preserving aspect ratio
            
                            scaleTransform = findScaleTransform(refDims, repDims);
            
                            outputView = imref2d(sizer);
                            imageContourScaled = imwarp(app.imageContour, scaleTransform, 'OutputView', outputView);
            
                            %% Apply estimated geometric transform to scaled replacement video frame
                            outputView = imref2d(size(cameraFrame));
                            imageContourTransformed = imwarp(imageContourScaled, referenceTransform, 'OutputView', outputView);
            
                            %% Insert transformed Video Frame into webcam frame
            
                            alphaBlender = vision.AlphaBlender('Operation', 'Binary mask', 'MaskSource', 'Input port');
            
                            mask = imageContourTransformed(:, :, 1) | imageContourTransformed(:,:,2) | imageContourTransformed(:,:,3) > 0;
            
                            outputFrame = step(alphaBlender, cameraFrame, imageContourTransformed, mask);
                            
                            step(videoPlayer, outputFrame);
                       
                        else
                           outputFrame = step(alphaBlender, cameraFrame, imageContourTransformed, mask);
                           step(videoPlayer, outputFrame);
                        end
                    elseif app.fix == "OUI"
                        outputFrame = step(alphaBlender, cameraFrame, imageContourTransformed, mask);
                        step(videoPlayer, outputFrame);
                    else
                        warning("Can't see the reference image in the camera frame");
                        step(videoPlayer, cameraFrame);
                    end
                catch ME
                    warning("An error has ocurred");
                    rethrow(ME);
                end
                runloop = isOpen(videoPlayer);
            end
            clearvars
        end

        % Value changed function: FixerSwitch
        function FixerSwitchValueChanged(app, event)
            app.fix = app.FixerSwitch.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DRAWHELPINGAPPLabel = uilabel(app.UIFigure);
            app.DRAWHELPINGAPPLabel.Text = 'DRAW HELPING APP';
            app.Panel = uipanel(app.UIFigure);
            app.ChargerImageButton = uibutton(app.Panel, 'push');
            app.ChargerImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerImageButtonPushed, true);
            app.ChargerImageButton.Text = 'Charger Image';
            app.LancerButton = uibutton(app.Panel, 'push');
            app.LancerButton.ButtonPushedFcn = createCallbackFcn(app, @LancerButtonPushed, true);
            app.LancerButton.Text = 'Lancer';
            app.FixerSwitchLabel = uilabel(app.Panel);
            app.FixerSwitchLabel.Text = 'Fixer';
            app.FixerSwitch = uiswitch(app.Panel, 'slider');
            app.FixerSwitch.Items = {'NON', 'OUI'};
            app.FixerSwitch.ValueChangedFcn = createCallbackFcn(app, @FixerSwitchValueChanged, true);
            app.FixerSwitch.Value = 'NON';
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, 'Reference')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2.Visible = 'off';
            app.LongueurEditField_2Label = uilabel(app.Panel);
            app.LongueurEditField_2Label.Text = 'Longueur';
            app.LongueurEditField = uieditfield(app.Panel, 'text');
            app.LargeurEditFieldLabel = uilabel(app.Panel);
            app.LargeurEditFieldLabel.Text = 'Largeur';
            app.LargeurEditField = uieditfield(app.Panel, 'text');
            app.TailleImageLabel = uilabel(app.Panel);
            app.TailleImageLabel.Text = 'Taille Image';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Target Image')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = helpDrawerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end